classdef ABRapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DataPanel                     matlab.ui.container.Panel
        UITable2                      matlab.ui.control.Table
        SaveTablesButton              matlab.ui.control.Button
        SavePlotsButton               matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        PlotOptionsPanel              matlab.ui.container.Panel
        StartAnalysisButton           matlab.ui.control.Button
        EndTimepointEditField         matlab.ui.control.NumericEditField
        EndTimepointEditFieldLabel    matlab.ui.control.Label
        BeginTimepointEditField       matlab.ui.control.NumericEditField
        BeginTimepointEditFieldLabel  matlab.ui.control.Label
        WaveIPointsCheckBox           matlab.ui.control.CheckBox
        FigLegendsCheckBox            matlab.ui.control.CheckBox
        LinkAxesCheckBox              matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        IndWFsTab                     matlab.ui.container.Tab
        OverlayTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        StimAmpTab                    matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        CompTab                       matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Get file names for the ABR data files
            dataFolder = uigetdir(Title='Select folder with files for analysis'); %use UI to select directory
            %want to set default directory to home dir
            [dataFiles, names] = getFileNames(dataFolder); %get names of ABR data files            
            app.UITable2.Data = cell2table((names)'); %display file names in uitable
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndWFsTab = uitab(app.TabGroup);
            app.IndWFsTab.Title = 'IndWFs';
            app.OverlayTab = uitab(app.TabGroup);
            app.OverlayTab.Title = 'Overlay';
            app.UIAxes = uiaxes(app.OverlayTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StimAmpTab = uitab(app.TabGroup);
            app.StimAmpTab.Title = 'StimAmp';
            app.UIAxes2 = uiaxes(app.StimAmpTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CompTab = uitab(app.TabGroup);
            app.CompTab.Title = 'Comp';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Stimulus level (dB)'; 'N1 amp'; 'P1 amp'; 'Wave I amp'; 'Wave I lat.'};
            app.UITable.RowName = {};
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.LinkAxesCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.FigLegendsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.FigLegendsCheckBox.Text = 'Fig. Legends';
            app.WaveIPointsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.WaveIPointsCheckBox.Text = 'Wave I Points';
            app.WaveIPointsCheckBox.Value = true;
            app.BeginTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.BeginTimepointEditFieldLabel.Text = 'Begin Timepoint';
            app.BeginTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.BeginTimepointEditField.Limits = [0 12];
            app.EndTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.EndTimepointEditFieldLabel.Text = 'End Timepoint';
            app.EndTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.EndTimepointEditField.Limits = [0 12];
            app.EndTimepointEditField.Value = 6;
            app.StartAnalysisButton = uibutton(app.PlotOptionsPanel, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SavePlotsButton = uibutton(app.DataPanel, 'push');
            app.SavePlotsButton.Text = 'Save Plots';
            app.SaveTablesButton = uibutton(app.DataPanel, 'push');
            app.SaveTablesButton.Text = 'Save Tables';
            app.UITable2 = uitable(app.DataPanel);
            app.UITable2.ColumnName = {'File Names'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABRapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end