classdef ABRapp_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DataPanel                     matlab.ui.container.Panel
        DatasetsloadedListBox         matlab.ui.control.ListBox
        DatasetsloadedListBoxLabel    matlab.ui.control.Label
        SaveTablesButton              matlab.ui.control.Button
        SavePlotsButton               matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        PlotOptionsPanel              matlab.ui.container.Panel
        DataselectedEditField         matlab.ui.control.EditField
        DataselectedEditFieldLabel    matlab.ui.control.Label
        EndTimepointEditField         matlab.ui.control.NumericEditField
        EndTimepointEditFieldLabel    matlab.ui.control.Label
        BeginTimepointEditField       matlab.ui.control.NumericEditField
        BeginTimepointEditFieldLabel  matlab.ui.control.Label
        WaveIPointsCheckBox           matlab.ui.control.CheckBox
        FigLegendsCheckBox            matlab.ui.control.CheckBox
        LinkAxesCheckBox              matlab.ui.control.CheckBox
        RightPanel                    matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        IndWFsTab                     matlab.ui.container.Tab
        OverlayTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        StimAmpTab                    matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        CompTab                       matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dataFolder % location of data files and where to save data
        allData % struct of all loaded ABR data
        activeData % struct of data selected for table/plots
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Get file names for the ABR data files by pressing Load Data
            %button. Opens UI dialog box to allow navigating directories
            app.dataFolder = uigetdir(Title='Select folder with files for analysis'); %use UI to select directory
            %want to set default directory to home dir
            [dataFiles, names] = getFileNames(app.dataFolder); %get names of ABR data files            
            
            %run identifyPeaks and store data in allData
            app.allData = identifyPeaks(dataFiles); 
            
            %populate list box
            app.DatasetsloadedListBox.Items = names;     
            
        end

        % Value changed function: DatasetsloadedListBox
        function DatasetsloadedListBoxValueChanged(app, event)
            value = app.DatasetsloadedListBox.Value; %select data file
            valInd = find(strcmp(app.DatasetsloadedListBox.Items, value)); %get index of list to retrieve other data
            
            %put make the selected data the activeData
            app.activeData = app.allData(valInd);
            app.DataselectedEditField.Value = app.activeData.Name; %display selected data name
            
            %Populate wave I data table
            dataTable = app.activeData.waveIdata(:, {'Stimulus level', 'N1 amplitude (µV)', ...
                'P1 amplitude (µV)', 'Wave I amplitude (µV)', 'Wave I latency (ms)'});
            app.UITable.Data = dataTable; %display data in table
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {671, 671};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotOptionsPanel = uipanel(app.LeftPanel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.LinkAxesCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.FigLegendsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.FigLegendsCheckBox.Text = 'Fig. Legends';
            app.WaveIPointsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.WaveIPointsCheckBox.Text = 'Wave I Points';
            app.WaveIPointsCheckBox.Value = true;
            app.BeginTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.BeginTimepointEditFieldLabel.Text = 'Begin Timepoint';
            app.BeginTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.BeginTimepointEditField.Limits = [0 12];
            app.EndTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.EndTimepointEditFieldLabel.Text = 'End Timepoint';
            app.EndTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.EndTimepointEditField.Limits = [0 12];
            app.EndTimepointEditField.Value = 6;
            app.DataselectedEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.DataselectedEditFieldLabel.Text = 'Data selected';
            app.DataselectedEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.DataPanel = uipanel(app.LeftPanel);
            app.DataPanel.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SavePlotsButton = uibutton(app.DataPanel, 'push');
            app.SavePlotsButton.Text = 'Save Plots';
            app.SaveTablesButton = uibutton(app.DataPanel, 'push');
            app.SaveTablesButton.Text = 'Save Tables';
            app.DatasetsloadedListBoxLabel = uilabel(app.DataPanel);
            app.DatasetsloadedListBoxLabel.Text = 'Data sets loaded';
            app.DatasetsloadedListBox = uilistbox(app.DataPanel);
            app.DatasetsloadedListBox.Items = {};
            app.DatasetsloadedListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsloadedListBoxValueChanged, true);
            app.DatasetsloadedListBox.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.IndWFsTab = uitab(app.TabGroup);
            app.IndWFsTab.Title = 'IndWFs';
            app.OverlayTab = uitab(app.TabGroup);
            app.OverlayTab.Title = 'Overlay';
            app.UIAxes = uiaxes(app.OverlayTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Wave I amplitude (µV)')
            zlabel(app.UIAxes, 'Z')
            app.StimAmpTab = uitab(app.TabGroup);
            app.StimAmpTab.Title = 'StimAmp';
            app.UIAxes2 = uiaxes(app.StimAmpTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Stimulus level (dB SPL)')
            ylabel(app.UIAxes2, 'Wave I amplitdue (µV)')
            zlabel(app.UIAxes2, 'Z')
            app.CompTab = uitab(app.TabGroup);
            app.CompTab.Title = 'Comp';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Stimulus level (dB)'; 'N1 amp'; 'P1 amp'; 'Wave I amp'; 'Wave I lat.'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABRapp_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end